classdef vindum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StopLoggerButton        matlab.ui.control.Button
        StartLoggerButton       matlab.ui.control.Button
        StopAllButton           matlab.ui.control.Button
        StopButtonCylB          matlab.ui.control.Button
        StartButtonCylB         matlab.ui.control.Button
        StopButtonCylA          matlab.ui.control.Button
        StartButtonCylA         matlab.ui.control.Button
        BRetractButton          matlab.ui.control.Button
        BExtendButton           matlab.ui.control.Button
        ARetractButton          matlab.ui.control.Button
        AExtendButton           matlab.ui.control.Button
        CylinderDirectionLabel  matlab.ui.control.Label
        PressB_Plimit           matlab.ui.control.NumericEditField
        PressureLimitsMPaLabel  matlab.ui.control.Label
        PressA_Plimit           matlab.ui.control.NumericEditField
        PressB_SP               matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        PressA_SP               matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        CylinderBDropDown       matlab.ui.control.DropDown
        CylinderADropDown       matlab.ui.control.DropDown
        SetPointsLabel          matlab.ui.control.Label
        ControlModesLabel       matlab.ui.control.Label
        BoutLabel               matlab.ui.control.Label
        valveBout               matlab.ui.control.StateButton
        BinLabel                matlab.ui.control.Label
        valveBin                matlab.ui.control.StateButton
        AinLabel                matlab.ui.control.Label
        valveAin                matlab.ui.control.StateButton
        AoutLabel               matlab.ui.control.Label
        valveAout               matlab.ui.control.StateButton
        Gauge_2                 matlab.ui.control.LinearGauge
        VolB_disp               matlab.ui.control.Label
        Qb_disp                 matlab.ui.control.Label
        VolA_disp               matlab.ui.control.Label
        Qa_disp                 matlab.ui.control.Label
        PressB_disp             matlab.ui.control.Label
        PressA_disp             matlab.ui.control.Label
        PressA_label_4          matlab.ui.control.Label
        PressA_label_3          matlab.ui.control.Label
        PressA_label_2          matlab.ui.control.Label
        CylinderBLabel          matlab.ui.control.Label
        CylinderALabel          matlab.ui.control.Label
        Gauge                   matlab.ui.control.LinearGauge
    end

    
    properties (Access = private)
        m % modbus
        tmr
        PpA
        tPa_s
        PpB
        tPb_s
        VolA
        tVa_s
        VolB
        tVb_s
        QA
        tQa_s
        QB
        tQb_s
        vdatTmr
        filepath
        filename
    end
    
    methods (Access = private)
        
        
        function dat = readDat(app, addr, pos)
            a=uint16(read(app.m,'inputregs', addr, pos));
            a=fliplr(a);
            c=typecast(a,'uint8');
            dat=typecast(c,'single');
        end

        function Press = readP(app, Cyl)
            err=0;
            if strcmp(Cyl,'A')
                Press = readDat(app, 0x02, 2);
            elseif strcmp(Cyl,'B')
                Press = readDat(app, 0x04, 2);
            else
                err=1;
            end
        end

        function Vol = readV(app, Cyl)
            err=0;
            if strcmp(Cyl,'A')
                Vol = readDat(app, 14, 2);
            elseif strcmp(Cyl,'B')
                Vol = readDat(app, 16, 2);
            else
                err=1;
            end
        end

        function Rate = readQ(app, Cyl)
            err=0;
            if strcmp(Cyl,'A')
                Rate = readDat(app, 0x08, 2);
            elseif strcmp(Cyl,'B')
                Rate = readDat(app, 10, 2);
            else
                err=1;
            end
        end

        function err=actValve(app,valve,pos)
            err=0;
            if length(pos(:)>1)
                if pos == 0 || pos == 1
                    if strcmp(valve,'Ain')
                        write(app.m,'coils', 0x01, pos);
                    elseif strcmp(valve,'Aout')
                        write(app.m,'coils',0x02, pos);
                    elseif strcmp(valve,'Bin')
                        write(app.m,'coils',0x03, pos);
                    elseif strcmp(valve,'Bout')
                        write(app.m,'coils',0x04, pos);
                    else
                        err=3;
                        disp('Can''t understand');
                    end
            
                else
                    err=2;
                    disp('Can''t understand');
                end
            else
                err=1;
                disp('Can''t understand');
            end
            % pause(0.1);
        end 

        function TmrFcn(app, parent_timer, function_type)
            % toc
            % app.PpA = readP(app, 'A');
            app.PressA_disp.Text = num2str(readP(app, 'A'));

            % app.VolA = readV(app, 'A');
            app.VolA_disp.Text = num2str(readV(app, 'A'));

            % app.PpB = readP(app, 'B');
            app.PressB_disp.Text = num2str(readP(app, 'B'));

            % app.VolB = readV(app, 'B');
            app.VolB_disp.Text = num2str(readV(app, 'B'));

            % app.QA = readQ(app, 'A');
            app.Qa_disp.Text = num2str(readQ(app, 'A'));

            % app.QB = readQ(app, 'B');
            app.Qb_disp.Text = num2str(readQ(app, 'B'));

            Apos = read(app.m, 'inputregs', 21, 1)/642 * 100;
            app.Gauge_2.Value = Apos;

            Bpos = read(app.m, 'inputregs', 22, 1)/642 * 100;
            app.Gauge.Value = Bpos;
            % end
       
        end

        function LogDatTmrFcn(app, parent_timer, function_type)
            
            readVal = readP(app, 'A');
            app.tPa_s = [app.tPa_s toc];
            app.PpA = [app.PpA readVal];

            readVal = readP(app, 'B');
            app.tPb_s = [app.tPb_s toc];
            app.PpB = [app.PpB readVal];

            readVal = readV(app, 'A');
            app.tVa_s = [app.tVa_s toc];
            app.VolA = [app.VolA readVal];
            
            readVal = readV(app, 'B');
            app.tVb_s = [app.tVb_s, toc];
            app.VolB = [app.VolB readVal];
            
            readVal = readQ(app, 'A');
            app.tQa_s = [app.tQa_s toc];
            app.QA = [app.QA readVal];

            readVal = readQ(app, 'B');
            app.tQb_s = [app.tQb_s toc];
            app.QB = [app.QB readVal];
            
            tPin_s = app.tPa_s; 
            Pin_MPa = app.PpA;
            tVin_s = app.tVa_s;
            Vin_ml = app.VolA;
            tQin_s = app.tQa_s;
            Qin_mlmin = app.QA;
            tPout_s = app.tPb_s;
            Pout_MPa = app.PpB;
            tVout_s = app.tVb_s;
            Vout_ml = app.VolB;
            tQout_s = app.tQa_s;
            Qout_mlmin = app.QB;
        
            save([app.filepath, app.filename], 'tPin_s', 'Pin_MPa', "tPout_s", "Pout_MPa", "tVin_s", "Vin_ml", "tVout_s", "Vout_ml", "tQin_s", "Qin_mlmin", "tQout_s", "Qout_mlmin",'-append')
       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.m = modbus('serialrtu','COM10','BaudRate',38400);
           pause(1)
            app.tmr = timer("ExecutionMode","fixedDelay","Period",2, ...
                "BusyMode","drop","TimerFcn",@app.TmrFcn);

            app.vdatTmr = timer("ExecutionMode","fixedDelay","Period",2, ...
                "BusyMode","drop","TimerFcn",@app.LogDatTmrFcn);
            
            % CLOSE ALL VALVES & SHOW STATE
            actValve(app, 'Ain', 1);
            app.valveAin.BackgroundColor = [1.0, 0.0 0.0];
            actValve(app, 'Aout', 1);
            app.valveAout.BackgroundColor = [1.0, 0.0 0.0];
            actValve(app, 'Bin', 1);
            app.valveBin.BackgroundColor = [1.0, 0.0 0.0];
            actValve(app, 'Bout', 1);
            app.valveBout.BackgroundColor = [1.0, 0.0 0.0];

            write(app.m, 'holding', 30, 3) %default units MPa
            write(app.m, 'holding', 31, 0) %default units ml/min 
            
            % tic
            start(app.tmr);
        end

        % Value changed function: valveAin
        function valveAinValueChanged(app, event)
            value = app.valveAin.Value;
            if value == 0
                actValve(app, 'Ain', double(value));
                app.valveAin.BackgroundColor = [0.0, 1.0, 0.0];
            elseif value == 1
                actValve(app, 'Ain', double(value));
                app.valveAin.BackgroundColor = [1.0, 0.0 0.0];
            end
        end

        % Value changed function: valveAout
        function valveAoutValueChanged(app, event)
            value = app.valveAout.Value;
            if value == 0
                actValve(app, 'Aout', double(value));
                app.valveAout.BackgroundColor = [0.0, 1.0, 0.0];
            elseif value == 1
                actValve(app, 'Aout', double(value));
                app.valveAout.BackgroundColor = [1.0, 0.0 0.0];
            end
        end

        % Value changed function: valveBin
        function valveBinValueChanged(app, event)
            value = app.valveBin.Value;
            if value == 0
                actValve(app, 'Bin', double(value));
                app.valveBin.BackgroundColor = [0.0, 1.0, 0.0];
            elseif value == 1
                actValve(app, 'Bin', double(value));
                app.valveBin.BackgroundColor = [1.0, 0.0 0.0];
            end
        end

        % Value changed function: valveBout
        function valveBoutValueChanged(app, event)
            value = app.valveBout.Value;
            if value == 0
                actValve(app, 'Bout', double(value));
                app.valveBout.BackgroundColor = [0.0, 1.0, 0.0];
            elseif value == 1
                actValve(app, 'Bout', double(value));
                app.valveBout.BackgroundColor = [1.0, 0.0 0.0];
            end
        end

        % Drop down opening function: CylinderADropDown
        function CylinderADropDownOpening(app, event)
            if strcmp(app.CylinderADropDown.Value, "Pressure")
                write(app.m, 'holdingregs', 0x03, double(2));
            else
                write(app.m, 'holdingregs', 0x03, double(1));
            end
        end

        % Drop down opening function: CylinderBDropDown
        function CylinderBDropDownOpening(app, event)
            if strcmp(app.CylinderBDropDown.Value, "Pressure")
                write(app.m, 'holdingregs', 0x04, double(2));
            else
                write(app.m, 'holdingregs', 0x04, double(1));
            end
        end

        % Value changed function: PressA_SP
        function PressA_SPValueChanged(app, event)
            value = app.PressA_SP.Value;
            setpoint = fliplr(typecast(single(value), 'uint16'));
            if strcmp(app.CylinderADropDown.Value, "Pressure")
                write(app.m, 'holdingregs', 12, double(setpoint))
            else
                write(app.m, 'holdingregs', 0x06, double(setpoint))
            end
        end

        % Value changed function: PressB_SP
        function PressB_SPValueChanged(app, event)
            value = app.PressB_SP.Value;
            setpoint = fliplr(typecast(single(value), 'uint16'));
            if strcmp(app.CylinderBDropDown.Value, "Pressure")
                write(app.m, 'holdingregs', 14, double(setpoint))
            else
                write(app.m, 'holdingregs', 0x08, double(setpoint))
            end
        end

        % Value changed function: PressA_Plimit
        function PressA_PlimitValueChanged(app, event)
            value = app.PressA_Plimit.Value;
            setpoint = fliplr(typecast(single(value), 'uint16'));
            write(app.m, 'holdingregs', 22, double(setpoint))
        end

        % Value changed function: PressB_Plimit
        function PressB_PlimitValueChanged(app, event)
            value = app.PressB_Plimit.Value;
            setpoint = fliplr(typecast(single(value), 'uint16'));
            write(app.m, 'holdingregs', 24, double(setpoint))
        end

        % Button pushed function: AExtendButton
        function AExtendButtonPushed(app, event)
            write(app.m, 'holdingregs', 0x01, 1)
        end

        % Button pushed function: ARetractButton
        function ARetractButtonPushed(app, event)
            write(app.m, 'holdingregs', 0x01, 2)
        end

        % Button pushed function: BExtendButton
        function BExtendButtonPushed(app, event)
            write(app.m, 'holdingregs', 0x02, 1)
        end

        % Button pushed function: BRetractButton
        function BRetractButtonPushed(app, event)
            write(app.m, 'holdingregs', 0x02, 2)
        end

        % Button pushed function: StopAllButton
        function StopAllButtonPushed(app, event)
            write(app.m, 'coils', 22, double(1))
        end

        % Button pushed function: StartButtonCylA
        function StartButtonCylAPushed(app, event)
            write(app.m, 'coils', 16, 1)
        end

        % Button pushed function: StopButtonCylA
        function StopButtonCylAPushed(app, event)
            write(app.m, 'coils', 20, 1)
        end

        % Button pushed function: StartButtonCylB
        function StartButtonCylBPushed(app, event)
            write(app.m, 'coils', 18, 1)
        end

        % Button pushed function: StopButtonCylB
        function StopButtonCylBPushed(app, event)
            write(app.m, 'coils', 21, 1)
        end

        % Button pushed function: StartLoggerButton
        function StartLoggerButtonPushed(app, event)
            [app.filename, app.filepath] = uiputfile('File Selection', 'experiment.mat');
            tPin_s = [];
            Pin_MPa = [];
            tPout_s = [];
            Pout_MPa = [];
            tVin_s = [];
            Vin_ml = [];
            tVout_s = [];
            Vout_ml = [];
            tQin_s = [];
            Qin_mlmin = [];
            tQout_s = [];
            Qout_mlmin = [];
            
            save([app.filepath, app.filename], 'tPin_s', 'Pin_MPa', 'tPout_s', 'Pout_MPa', 'tVin_s', 'Vin_ml', 'tVout_s', 'Vout_ml', 'tQin_s', 'Qin_mlmin', 'tQout_s', 'Qout_mlmin');
            % app.timestart = datestr(now);
            tic
            start(app.vdatTmr)
            set(app.StopLoggerButton, 'Enable', 1)
            set(app.StartLoggerButton, 'Enable', 0)

        end

        % Button pushed function: StopLoggerButton
        function StopLoggerButtonPushed(app, event)
            stop(app.vdatTmr)
            set(app.StopLoggerButton, 'Enable', 0)
            set(app.StartLoggerButton, 'Enable', 1)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.tmr)
            stop(app.vdatTmr)
            write(app.m, 'coils', 22, double(1))
            pause(1)
            actValve(app, 'Ain', 1);
            actValve(app, 'Aout', 1);
            actValve(app, 'Bin', 1);
            actValve(app, 'Bout', 1);
            clear app.m
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.CylinderALabel = uilabel(app.UIFigure);
            app.CylinderALabel.Text = 'Cylinder A';
            app.CylinderBLabel = uilabel(app.UIFigure);
            app.CylinderBLabel.Text = 'Cylinder B';
            app.PressA_label_2 = uilabel(app.UIFigure);
            app.PressA_label_2.Text = 'Pressure (MPa)';
            app.PressA_label_3 = uilabel(app.UIFigure);
            app.PressA_label_3.Text = 'Rate (mL/min)';
            app.PressA_label_4 = uilabel(app.UIFigure);
            app.PressA_label_4.Text = 'Volume (mL)';
            app.PressA_disp = uilabel(app.UIFigure);
            app.PressA_disp.Text = '---';
            app.PressB_disp = uilabel(app.UIFigure);
            app.PressB_disp.Text = '---';
            app.Qa_disp = uilabel(app.UIFigure);
            app.Qa_disp.Text = '---';
            app.VolA_disp = uilabel(app.UIFigure);
            app.VolA_disp.Text = '---';
            app.Qb_disp = uilabel(app.UIFigure);
            app.Qb_disp.Text = '---';
            app.VolB_disp = uilabel(app.UIFigure);
            app.VolB_disp.Text = '---';
            app.Gauge_2 = uigauge(app.UIFigure, 'linear');
            app.valveAout = uibutton(app.UIFigure, 'state');
            app.valveAout.ValueChangedFcn = createCallbackFcn(app, @valveAoutValueChanged, true);
            app.valveAout.Text = '';
            app.valveAout.Value = true;
            app.AoutLabel = uilabel(app.UIFigure);
            app.AoutLabel.Text = 'Aout';
            app.valveAin = uibutton(app.UIFigure, 'state');
            app.valveAin.ValueChangedFcn = createCallbackFcn(app, @valveAinValueChanged, true);
            app.valveAin.Text = '';
            app.valveAin.Value = true;
            app.AinLabel = uilabel(app.UIFigure);
            app.AinLabel.Text = 'Ain';
            app.valveBin = uibutton(app.UIFigure, 'state');
            app.valveBin.ValueChangedFcn = createCallbackFcn(app, @valveBinValueChanged, true);
            app.valveBin.Text = '';
            app.valveBin.Value = true;
            app.BinLabel = uilabel(app.UIFigure);
            app.BinLabel.Text = 'Bin';
            app.valveBout = uibutton(app.UIFigure, 'state');
            app.valveBout.ValueChangedFcn = createCallbackFcn(app, @valveBoutValueChanged, true);
            app.valveBout.Text = '';
            app.valveBout.Value = true;
            app.BoutLabel = uilabel(app.UIFigure);
            app.BoutLabel.Text = 'Bout';
            app.ControlModesLabel = uilabel(app.UIFigure);
            app.ControlModesLabel.Text = 'Control Modes';
            app.SetPointsLabel = uilabel(app.UIFigure);
            app.SetPointsLabel.Text = 'Set Points';
            app.CylinderADropDown = uidropdown(app.UIFigure);
            app.CylinderADropDown.Items = {'Pressure', 'Rate'};
            app.CylinderADropDown.DropDownOpeningFcn = createCallbackFcn(app, @CylinderADropDownOpening, true);
            app.CylinderADropDown.Placeholder = ' ';
            app.CylinderADropDown.Value = 'Pressure';
            app.CylinderBDropDown = uidropdown(app.UIFigure);
            app.CylinderBDropDown.Items = {'Pressure', 'Rate'};
            app.CylinderBDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CylinderBDropDownOpening, true);
            app.CylinderBDropDown.Placeholder = ' ';
            app.CylinderBDropDown.Value = 'Pressure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '---';
            app.PressA_SP = uieditfield(app.UIFigure, 'numeric');
            app.PressA_SP.ValueChangedFcn = createCallbackFcn(app, @PressA_SPValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '---';
            app.PressB_SP = uieditfield(app.UIFigure, 'numeric');
            app.PressB_SP.ValueChangedFcn = createCallbackFcn(app, @PressB_SPValueChanged, true);
            app.PressA_Plimit = uieditfield(app.UIFigure, 'numeric');
            app.PressA_Plimit.ValueChangedFcn = createCallbackFcn(app, @PressA_PlimitValueChanged, true);
            app.PressureLimitsMPaLabel = uilabel(app.UIFigure);
            app.PressureLimitsMPaLabel.Text = 'Pressure Limits (MPa)';
            app.PressB_Plimit = uieditfield(app.UIFigure, 'numeric');
            app.PressB_Plimit.ValueChangedFcn = createCallbackFcn(app, @PressB_PlimitValueChanged, true);
            app.CylinderDirectionLabel = uilabel(app.UIFigure);
            app.CylinderDirectionLabel.Text = 'Cylinder Direction';
            app.AExtendButton = uibutton(app.UIFigure, 'push');
            app.AExtendButton.ButtonPushedFcn = createCallbackFcn(app, @AExtendButtonPushed, true);
            app.AExtendButton.Text = 'Extend';
            app.ARetractButton = uibutton(app.UIFigure, 'push');
            app.ARetractButton.ButtonPushedFcn = createCallbackFcn(app, @ARetractButtonPushed, true);
            app.ARetractButton.Text = 'Retract';
            app.BExtendButton = uibutton(app.UIFigure, 'push');
            app.BExtendButton.ButtonPushedFcn = createCallbackFcn(app, @BExtendButtonPushed, true);
            app.BExtendButton.Text = 'Extend';
            app.BRetractButton = uibutton(app.UIFigure, 'push');
            app.BRetractButton.ButtonPushedFcn = createCallbackFcn(app, @BRetractButtonPushed, true);
            app.BRetractButton.Text = 'Retract';
            app.StartButtonCylA = uibutton(app.UIFigure, 'push');
            app.StartButtonCylA.ButtonPushedFcn = createCallbackFcn(app, @StartButtonCylAPushed, true);
            app.StartButtonCylA.Text = 'Start';
            app.StopButtonCylA = uibutton(app.UIFigure, 'push');
            app.StopButtonCylA.ButtonPushedFcn = createCallbackFcn(app, @StopButtonCylAPushed, true);
            app.StopButtonCylA.Text = 'Stop';
            app.StartButtonCylB = uibutton(app.UIFigure, 'push');
            app.StartButtonCylB.ButtonPushedFcn = createCallbackFcn(app, @StartButtonCylBPushed, true);
            app.StartButtonCylB.Text = 'Start';
            app.StopButtonCylB = uibutton(app.UIFigure, 'push');
            app.StopButtonCylB.ButtonPushedFcn = createCallbackFcn(app, @StopButtonCylBPushed, true);
            app.StopButtonCylB.Text = 'Stop';
            app.StopAllButton = uibutton(app.UIFigure, 'push');
            app.StopAllButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllButtonPushed, true);
            app.StopAllButton.Text = 'Stop All';
            app.StartLoggerButton = uibutton(app.UIFigure, 'push');
            app.StartLoggerButton.ButtonPushedFcn = createCallbackFcn(app, @StartLoggerButtonPushed, true);
            app.StartLoggerButton.Text = 'Start Logger';
            app.StopLoggerButton = uibutton(app.UIFigure, 'push');
            app.StopLoggerButton.ButtonPushedFcn = createCallbackFcn(app, @StopLoggerButtonPushed, true);
            app.StopLoggerButton.Text = 'Stop Logger';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vindum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end